classdef Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        simplecalculatorUIFigure  matlab.ui.Figure
        CButton                   matlab.ui.control.Button
        CEButton                  matlab.ui.control.Button
        MSButton                  matlab.ui.control.Button
        MButton                   matlab.ui.control.Button
        MButton_2                 matlab.ui.control.Button
        Button_leftbracket        matlab.ui.control.Button
        Button_rightbracket       matlab.ui.control.Button
        xButton_2                 matlab.ui.control.Button
        xButton                   matlab.ui.control.Button
        Button_square             matlab.ui.control.Button
        Button_7                  matlab.ui.control.Button
        Button_8                  matlab.ui.control.Button
        Button_9                  matlab.ui.control.Button
        Button_add                matlab.ui.control.Button
        Button_sign               matlab.ui.control.Button
        Button_4                  matlab.ui.control.Button
        Button_5                  matlab.ui.control.Button
        Button_6                  matlab.ui.control.Button
        Button_sub                matlab.ui.control.Button
        Button_percentage         matlab.ui.control.Button
        Button_1                  matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        Button_3                  matlab.ui.control.Button
        Button_multiply           matlab.ui.control.Button
        Button_equal              matlab.ui.control.Button
        Button_0                  matlab.ui.control.Button
        Button_point              matlab.ui.control.Button
        Button_division           matlab.ui.control.Button
        TextArea                  matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        point_flag = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_0, Button_1, Button_2, 
        % Button_3, Button_4, Button_5, Button_6, Button_7, 
        % Button_8, Button_9, Button_point
        function ButtonPressed(app, event)
            app.TextArea.Value = [app.TextArea.Value{1}, event.Source.Text];
        end

        % Button pushed function: Button_equal
        function Button_equalPushed(app, event)
            app.TextArea.Value = [app.TextArea.Value{1} event.Source.Text ...
                num2str(eval(app.TextArea.Value{1}))];
        end

        % Button pushed function: Button_add
        function Button_addPushed(app, event)
            str=app.TextArea.Value;
            str=strcat(str,'+');
            app.TextArea.Value=str;
        end

        % Button pushed function: Button_sub
        function Button_subPushed(app, event)
            str=app.TextArea.Value;
            str=strcat(str,'-');
            app.TextArea.Value=str;
        end

        % Button pushed function: Button_multiply
        function Button_multiplyPushed(app, event)
            str=app.TextArea.Value;
            str=strcat(str,'*');
            app.TextArea.Value=str;
        end

        % Button pushed function: Button_division
        function Button_divisionPushed(app, event)
            str=app.TextArea.Value;
            str=strcat(str,'/');
            app.TextArea.Value=str;
        end

        % Button pushed function: Button_leftbracket
        function Button_leftbracketPushed(app, event)
            str=app.TextArea.Value;
            str=strcat(str,'(');
            app.TextArea.Value=str;
        end

        % Button pushed function: Button_rightbracket
        function Button_rightbracketPushed(app, event)
            str=app.TextArea.Value;
            str=strcat(str,')');
            app.TextArea.Value=str;
        end

        % Button pushed function: CEButton
        function CEButtonPushed(app, event)
            str=app.TextArea.Value;
            str=str(1:end-1);
            app.TextArea.Value=str;
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            app.TextArea.Value='';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.simplecalculatorUIFigure = uifigure('Visible', 'off');
            app.simplecalculatorUIFigure.Name = 'simple calculator';
            app.CButton = uibutton(app.simplecalculatorUIFigure, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.CEButton = uibutton(app.simplecalculatorUIFigure, 'push');
            app.CEButton.ButtonPushedFcn = createCallbackFcn(app, @CEButtonPushed, true);
            app.CEButton.Text = 'CE';
            app.MSButton = uibutton(app.simplecalculatorUIFigure, 'push');
            app.MSButton.Text = 'MS';
            app.MButton = uibutton(app.simplecalculatorUIFigure, 'push');
            app.MButton.Text = 'M+';
            app.MButton_2 = uibutton(app.simplecalculatorUIFigure, 'push');
            app.MButton_2.Text = 'M-';
            app.Button_leftbracket = uibutton(app.simplecalculatorUIFigure, 'push');
            app.Button_leftbracket.ButtonPushedFcn = createCallbackFcn(app, @Button_leftbracketPushed, true);
            app.Button_leftbracket.Text = '(';
            app.Button_rightbracket = uibutton(app.simplecalculatorUIFigure, 'push');
            app.Button_rightbracket.ButtonPushedFcn = createCallbackFcn(app, @Button_rightbracketPushed, true);
            app.Button_rightbracket.Text = ')';
            app.xButton_2 = uibutton(app.simplecalculatorUIFigure, 'push');
            app.xButton_2.Text = '1/x';
            app.xButton = uibutton(app.simplecalculatorUIFigure, 'push');
            app.xButton.Text = 'x²';
            app.Button_square = uibutton(app.simplecalculatorUIFigure, 'push');
            app.Button_square.Text = '√';
            app.Button_7 = uibutton(app.simplecalculatorUIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @ButtonPressed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.simplecalculatorUIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @ButtonPressed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.simplecalculatorUIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @ButtonPressed, true);
            app.Button_9.Text = '9';
            app.Button_add = uibutton(app.simplecalculatorUIFigure, 'push');
            app.Button_add.ButtonPushedFcn = createCallbackFcn(app, @Button_addPushed, true);
            app.Button_add.Text = '+';
            app.Button_sign = uibutton(app.simplecalculatorUIFigure, 'push');
            app.Button_sign.Text = '±';
            app.Button_4 = uibutton(app.simplecalculatorUIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @ButtonPressed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.simplecalculatorUIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @ButtonPressed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.simplecalculatorUIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @ButtonPressed, true);
            app.Button_6.Text = '6';
            app.Button_sub = uibutton(app.simplecalculatorUIFigure, 'push');
            app.Button_sub.ButtonPushedFcn = createCallbackFcn(app, @Button_subPushed, true);
            app.Button_sub.Text = '-';
            app.Button_percentage = uibutton(app.simplecalculatorUIFigure, 'push');
            app.Button_percentage.Text = '%';
            app.Button_1 = uibutton(app.simplecalculatorUIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @ButtonPressed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.simplecalculatorUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPressed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.simplecalculatorUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPressed, true);
            app.Button_3.Text = '3';
            app.Button_multiply = uibutton(app.simplecalculatorUIFigure, 'push');
            app.Button_multiply.ButtonPushedFcn = createCallbackFcn(app, @Button_multiplyPushed, true);
            app.Button_multiply.Text = '×';
            app.Button_equal = uibutton(app.simplecalculatorUIFigure, 'push');
            app.Button_equal.ButtonPushedFcn = createCallbackFcn(app, @Button_equalPushed, true);
            app.Button_equal.Text = '=';
            app.Button_0 = uibutton(app.simplecalculatorUIFigure, 'push');
            app.Button_0.ButtonPushedFcn = createCallbackFcn(app, @ButtonPressed, true);
            app.Button_0.Text = '0';
            app.Button_point = uibutton(app.simplecalculatorUIFigure, 'push');
            app.Button_point.ButtonPushedFcn = createCallbackFcn(app, @ButtonPressed, true);
            app.Button_point.Text = '.';
            app.Button_division = uibutton(app.simplecalculatorUIFigure, 'push');
            app.Button_division.ButtonPushedFcn = createCallbackFcn(app, @Button_divisionPushed, true);
            app.Button_division.Text = '÷';
            app.TextArea = uitextarea(app.simplecalculatorUIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'0'};
            % Show the figure after all components are created
            app.simplecalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.simplecalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.simplecalculatorUIFigure)
        end
    end
end